classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RenderButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        SetupPanel                matlab.ui.container.Panel
        SetuppathtracerButton     matlab.ui.control.Button
        xresLabel                 matlab.ui.control.Label
        xresEditField             matlab.ui.control.NumericEditField
        yresLabel                 matlab.ui.control.Label
        yresEditField             matlab.ui.control.NumericEditField
        ScenenameListBoxLabel     matlab.ui.control.Label
        ScenenameListBox          matlab.ui.control.ListBox
        nbrsamplesEditFieldLabel  matlab.ui.control.Label
        nbrsamplesEditField       matlab.ui.control.NumericEditField
        maxdepthEditFieldLabel    matlab.ui.control.Label
        maxdepthEditField         matlab.ui.control.NumericEditField
        SaveimageButton           matlab.ui.control.Button
        RendertimeLabel           matlab.ui.control.Label
        SamplestakenLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        mPathTracer@PathTracer % Description
        mSetupComplete@logical = false
    end


    methods (Access = private)

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            if (app.mSetupComplete)
                app.UIAxes.XLim = [0,app.xresEditField.Value+1];
                app.UIAxes.YLim = [0,app.yresEditField.Value+1];
                app.RenderButton.BackgroundColor = [1 0 0];
                pause(0.01);
                renderTime = app.mPathTracer.render(app);
                app.RenderButton.BackgroundColor = [0.96 0.96 0.96];
                app.SaveimageButton.BackgroundColor = [1 0 0];
                app.RendertimeLabel.Text = strcat('Render time:: ', num2str(renderTime), ' s');
            else
                error("Setup is not complete!");
            end
        end

        % Button pushed function: SetuppathtracerButton
        function SetuppathtracerButtonPushed(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            x = app.xresEditField.Value;
            y = app.yresEditField.Value;
            axis(app.UIAxes, 'equal');
            meshName = app.ScenenameListBox.Value;
            nbrSamples = app.nbrsamplesEditField.Value;
            pathDepth = app.maxdepthEditField.Value;
            app.mPathTracer = PathTracer(x,y, meshName, nbrSamples, pathDepth);
            app.mSetupComplete = true;
            app.RenderButton.Enable = 'on';
            app.SaveimageButton.Enable = 'on';
            app.SetuppathtracerButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if (app.mSetupComplete)
                app.SaveimageButton.BackgroundColor = [1 0 0];
                app.mPathTracer.writeOutputToFile();
                app.SaveimageButton.BackgroundColor = [0 1 0];

            else
                error("Setup is not complete!");
            end
        end

        % Value changed function: nbrsamplesEditField
        function nbrsamplesEditFieldValueChanged(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            
        end

        % Value changed function: ScenenameListBox
        function ScenenameListBoxValueChanged(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            
        end

        % Value changed function: xresEditField
        function xresEditFieldValueChanged(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            
        end

        % Value changed function: yresEditField
        function yresEditFieldValueChanged(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            
        end

        % Value changed function: maxdepthEditField
        function maxdepthEditFieldValueChanged(app, event)
            app.SetuppathtracerButton.BackgroundColor = [1 0 0];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Enable = 'off';
            app.RenderButton.Text = 'Render';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.SetuppathtracerButton = uibutton(app.SetupPanel, 'push');
            app.SetuppathtracerButton.ButtonPushedFcn = createCallbackFcn(app, @SetuppathtracerButtonPushed, true);
            app.SetuppathtracerButton.Text = 'Set up path tracer';
            app.xresLabel = uilabel(app.SetupPanel);
            app.xresLabel.Text = 'x res';
            app.xresEditField = uieditfield(app.SetupPanel, 'numeric');
            app.xresEditField.UpperLimitInclusive = 'off';
            app.xresEditField.Limits = [1 Inf];
            app.xresEditField.RoundFractionalValues = 'on';
            app.xresEditField.ValueDisplayFormat = '%.0f';
            app.xresEditField.ValueChangedFcn = createCallbackFcn(app, @xresEditFieldValueChanged, true);
            app.xresEditField.Value = 1;
            app.yresLabel = uilabel(app.SetupPanel);
            app.yresLabel.Text = 'y res';
            app.yresEditField = uieditfield(app.SetupPanel, 'numeric');
            app.yresEditField.UpperLimitInclusive = 'off';
            app.yresEditField.Limits = [1 Inf];
            app.yresEditField.RoundFractionalValues = 'on';
            app.yresEditField.ValueDisplayFormat = '%.0f';
            app.yresEditField.ValueChangedFcn = createCallbackFcn(app, @yresEditFieldValueChanged, true);
            app.yresEditField.Value = 1;
            app.ScenenameListBoxLabel = uilabel(app.SetupPanel);
            app.ScenenameListBoxLabel.Text = 'Scene name';
            app.ScenenameListBox = uilistbox(app.SetupPanel);
            app.ScenenameListBox.Items = {'Single Triangle', 'Spheres', 'null'};
            app.ScenenameListBox.ValueChangedFcn = createCallbackFcn(app, @ScenenameListBoxValueChanged, true);
            app.ScenenameListBox.Value = 'Single Triangle';
            app.nbrsamplesEditFieldLabel = uilabel(app.SetupPanel);
            app.nbrsamplesEditFieldLabel.Text = 'nbr samples';
            app.nbrsamplesEditField = uieditfield(app.SetupPanel, 'numeric');
            app.nbrsamplesEditField.Limits = [0 Inf];
            app.nbrsamplesEditField.RoundFractionalValues = 'on';
            app.nbrsamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nbrsamplesEditFieldValueChanged, true);
            app.nbrsamplesEditField.Value = 1;
            app.maxdepthEditFieldLabel = uilabel(app.SetupPanel);
            app.maxdepthEditFieldLabel.Text = 'max depth';
            app.maxdepthEditField = uieditfield(app.SetupPanel, 'numeric');
            app.maxdepthEditField.Limits = [1 10];
            app.maxdepthEditField.RoundFractionalValues = 'on';
            app.maxdepthEditField.ValueChangedFcn = createCallbackFcn(app, @maxdepthEditFieldValueChanged, true);
            app.maxdepthEditField.Value = 2;
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Enable = 'off';
            app.SaveimageButton.Text = 'Save image';
            app.RendertimeLabel = uilabel(app.UIFigure);
            app.RendertimeLabel.Text = 'Render time:';
            app.SamplestakenLabel = uilabel(app.UIFigure);
            app.SamplestakenLabel.Text = 'Samples taken:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end